classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EntradaTab                     matlab.ui.container.Tab
        TiemposEditField               matlab.ui.control.NumericEditField
        TiemposEditFieldLabel          matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        FsEditField                    matlab.ui.control.NumericEditField
        FsEditFieldLabel               matlab.ui.control.Label
        CargarmatButton                matlab.ui.control.Button
        CapturarButton                 matlab.ui.control.Button
        OfflineLabel                   matlab.ui.control.Label
        OnlineLabel                    matlab.ui.control.Label
        BaudrateDropDown               matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        SourceButtonGroup              matlab.ui.container.ButtonGroup
        OfflineButton                  matlab.ui.control.RadioButton
        OnlineButton                   matlab.ui.control.RadioButton
        DetenerButton                  matlab.ui.control.Button
        LimpiarButton                  matlab.ui.control.Button
        ConectarButton                 matlab.ui.control.Button
        SeleccionarelCOMDropDown       matlab.ui.control.DropDown
        SeleccionarelCOMDropDownLabel  matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        EditField_6                    matlab.ui.control.EditField
        EditField_3                    matlab.ui.control.EditField
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        DiseoOfflineTab                matlab.ui.container.Tab
        AlternarejeVerticalMagnitudButtonGroup  matlab.ui.container.ButtonGroup
        LinealButton                   matlab.ui.control.RadioButton
        dBButton                       matlab.ui.control.RadioButton
        AlternarejeHorizontalMagnitudButtonGroup  matlab.ui.container.ButtonGroup
        RadMuestraButton_2             matlab.ui.control.RadioButton
        HzButton_2                     matlab.ui.control.RadioButton
        AlternarejeHorizontalFaseButtonGroup  matlab.ui.container.ButtonGroup
        RadMuestraButton               matlab.ui.control.RadioButton
        HzButton                       matlab.ui.control.RadioButton
        AplicarButton                  matlab.ui.control.Button
        Fcorte2EditField               matlab.ui.control.NumericEditField
        Fcorte2EditFieldLabel          matlab.ui.control.Label
        Fcorte1EditField               matlab.ui.control.NumericEditField
        Fcorte1EditFieldLabel          matlab.ui.control.Label
        OrdenEditField                 matlab.ui.control.NumericEditField
        OrdenEditFieldLabel            matlab.ui.control.Label
        RetrasoEditField               matlab.ui.control.NumericEditField
        RetrasoEditFieldLabel          matlab.ui.control.Label
        GananciaEditField              matlab.ui.control.NumericEditField
        GananciaEditFieldLabel         matlab.ui.control.Label
        FcEditField                    matlab.ui.control.NumericEditField
        FcEditFieldLabel               matlab.ui.control.Label
        EfectosDropDown                matlab.ui.control.DropDown
        EfectosDropDownLabel           matlab.ui.control.Label
        EditField_5                    matlab.ui.control.EditField
        FiltroDropDown                 matlab.ui.control.DropDown
        FiltroDropDownLabel            matlab.ui.control.Label
        TipodeFiltroButtonGroup        matlab.ui.container.ButtonGroup
        FIRButton                      matlab.ui.control.RadioButton
        IIRButton                      matlab.ui.control.RadioButton
        EditField_4                    matlab.ui.control.EditField
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        DiseoOnlineTab                 matlab.ui.container.Tab
        SalidaTab                      matlab.ui.container.Tab
    end

    
    properties (Access = private)
        signal_original % Description
        Fs % Description
        stm32 %Variable stm32 para generar la conexion
        a_coeff %Coeficiente de a 
        b_coeff %Coeficiente de b
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CargarmatButtonPushed(app, event)
            [file, path] = uigetfile('*mat', 'Seleccionar el archivo por cargar');


            if isequal(file, 0)
                return;
            end
         data = load(fullfile(path, file));

         if isfield(data, 'x') && isfield(data, 'Fs')
             signal = data.x;
             fs = data.Fs;
             app.FsEditField.Value = fs;
             app.MuestrasEditField.Value = length(signal);
             app.signal_original = signal;
             app.Fs = fs;
             n = length(signal);
             L = n;
             t = (0:n-1)/fs;

             plot(app.UIAxes_12, t, signal);
             title(app.UIAxes_12, 'Señal original cargada');
             xlabel(app.UIAxes_12, 'Tiempo (s)');
             ylabel(app.UIAxes_12, 'Amplitud');

             signal_sin_dc = signal - mean(signal);
             Y = fft(signal_sin_dc);
             espectro = abs(Y/L);
             espectro_uni = espectro(1 : floor(L/2) + 1);
             espectro_uni(2:end-1) = 2 * espectro_uni(2:end-1);

             frecuencia = fs*(0 : (L/2)) / L;
             plot(app.UIAxes_13, frecuencia, espectro_uni);
             title(app.UIAxes_13, 'Señal en dominio de la frecuencia');
             xlabel(app.UIAxes_13, 'Frecuencia (Hz)');
             ylabel(app.UIAxes_13, 'Amplitud');

             histogram(app.UIAxes_14, signal, 'NumBins',50, 'FaceColor', '#0072BD', 'EdgeColor', 'w');
             title(app.UIAxes_14, 'Distribucion de Amplitud (Histograma)');
             xlabel(app.UIAxes_14, 'Amplitud');
             ylabel(app.UIAxes_14, 'Conteo (Ocurrencias)');


         else
             uialert(app.UIFigure, 'El archivo no contiene las variables x y Fs', 'Error de formato :(');
         end


        end

        % Callback function
        function FsEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function SourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.SourceButtonGroup.SelectedObject;
            
            if selectedButton == app.OfflineButton
                app.COMDropDown.Enable = 'off';
                app.ConectarButton.Enable = 'off';
                app.CapturarButton.Enable = 'off';
                app.DetenerButton.Enable = 'off';
                app.TiemposEditField.Enable = 'off';
                
                % Encender controles Offline
                app.CargarmatButton.Enable = 'on';
                app.FsEditField.Enable = 'on';
                app.MuestrasEditField.Enable = 'on';
                
            elseif selectedButton == app.OnlineButton
                app.COMDropDown.Enable = 'on';
                app.ConectarButton.Enable = 'on';
                app.CapturarButton.Enable = 'on';
                app.DetenerButton.Enable = 'on';
                app.TiemposEditField.Enable = 'on';
                
                % Apagar controles Offline
                app.CargarmatButton.Enable = 'off';
                app.FsEditField.Enable = 'off';
                app.MuestrasEditField.Enable = 'off';
            end
            
        end

        % Callback function
        function LimpiarButtonPushed(app, event)
            app.signal_original = [];
            app.Fs = [];

            app.FsEditField.Value = 0;
            app.MuestrasEditField.Value = 0;
            app.TiemposEditField.Value = 0;
            app.NomuestrasEditField.Value = 0;

            cla(app.UIAxes_12);
            cla(app.UIAxes_13);
            cla(app.UIAxes_14);

        end

        % Callback function
        function ConectarButtonPushed(app, event)
            puerto_seleccionado = app.COMDropDown.Value;
            baudrate = str2double(app.BaudrateDropDown.Value);
            
            try
                app.stm32 = serialport(puerto_seleccionado, baudrate); %Conectar con stm32
                app.ConectarButton.Text = 'Conectado';
                app.ConectarButton.BackgroundColor = [0.47, 0.87, 0.47]; %Boton verde
                app.COMDropDown.Enable = 'off'; %Bloquear el COM para que no lo muevan

            catch ME
                uialert(app.UIFigure, ['No se pudo conectar a ' puerto_seleccionado '. Error']); %Mensaje de error
                app.ConectarButton.Text = 'Conectar';
                app.ConectarButton.BackgroundColor = [0.9, 0.9, 0.9]; %Si no se puede conectar el boton vuelve a la normalidad
            end
        end

        % Callback function
        function DetenerButtonPushed(app, event)
            if ~isempty(app.stm32)
                clear app.stm32;
                app.stm32 = [];
            end
            app.ConectarButton.Text = 'Conectar';
            app.ConectarButton.BackgroundColor = [0.9, 0.9, 0.9];
            app.COMDropDown = 'on';
            
        end

        % Callback function
        function TipodefiltroButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.TipodefiltroButtonGroup_2.SelectedObject;
            
        end

        % Callback function
        function AplicarButton_2Pushed(app, event)
            if isempty(app.Fs)
                uialert(app.UIFigure, 'Primero debe cargar un archivo en la pestaña de entrada', 'Falta Fs');
            end

            orden = app.OrdenEditField;
            fc = app.Fcorte1EditField;

            f_nyquist = app.Fs / 2;
            wn = fc / f_nyquist;

            %Aca va a ir el if para ver que el filtro a aplicar y el
            %dropdown del tipo.

            [app.b_coeff, app.a_coeff] = butter(orden, wn, 'low');
            [h, f_hz] = freqz(app.b_coeff, app.a_coeff, 1024, app.Fs);
            w_rad = (f_hz / f_nyquist) * pi;

            
            if app.dBButton.Value == true
                magnitud = 20 * log10(abs(h));
                ylabel_text = 'Magnitud (dB)';
            else
                magnitud = abs(h);
                ylabel_text = 'Magnitud (Lineal)';
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntradaTab = uitab(app.TabGroup);
            app.EntradaTab.Title = 'Entrada';
            app.UIAxes = uiaxes(app.EntradaTab);
            title(app.UIAxes, 'Señal Original')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.EntradaTab);
            title(app.UIAxes2, 'Señal en dominio de frecuencia')
            xlabel(app.UIAxes2, 'Frecuencia (z)')
            ylabel(app.UIAxes2, 'Amplitud')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.EntradaTab);
            title(app.UIAxes3, 'Histograma de Amplitud (Ruido)')
            xlabel(app.UIAxes3, 'Amplitud')
            ylabel(app.UIAxes3, 'Conteo')
            zlabel(app.UIAxes3, 'Z')
            app.EditField_3 = uieditfield(app.EntradaTab, 'text');
            app.EditField_6 = uieditfield(app.EntradaTab, 'text');
            app.EditField_2 = uieditfield(app.EntradaTab, 'text');
            app.EditField = uieditfield(app.EntradaTab, 'text');
            app.SeleccionarelCOMDropDownLabel = uilabel(app.EntradaTab);
            app.SeleccionarelCOMDropDownLabel.Text = 'Seleccionar el COM';
            app.SeleccionarelCOMDropDown = uidropdown(app.EntradaTab);
            app.SeleccionarelCOMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12'};
            app.SeleccionarelCOMDropDown.Value = 'COM1';
            app.ConectarButton = uibutton(app.EntradaTab, 'push');
            app.ConectarButton.Text = 'Conectar';
            app.LimpiarButton = uibutton(app.EntradaTab, 'push');
            app.LimpiarButton.Text = 'Limpiar';
            app.DetenerButton = uibutton(app.EntradaTab, 'push');
            app.DetenerButton.Text = 'Detener';
            app.SourceButtonGroup = uibuttongroup(app.EntradaTab);
            app.SourceButtonGroup.Title = 'Source';
            app.OnlineButton = uiradiobutton(app.SourceButtonGroup);
            app.OnlineButton.Text = 'Online';
            app.OnlineButton.Value = true;
            app.OfflineButton = uiradiobutton(app.SourceButtonGroup);
            app.OfflineButton.Text = 'Offline';
            app.BaudrateDropDownLabel = uilabel(app.EntradaTab);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.EntradaTab);
            app.BaudrateDropDown.Items = {'9600', '115200'};
            app.BaudrateDropDown.Value = '9600';
            app.OnlineLabel = uilabel(app.EntradaTab);
            app.OnlineLabel.Text = 'Online';
            app.OfflineLabel = uilabel(app.EntradaTab);
            app.OfflineLabel.Text = 'Offline';
            app.CapturarButton = uibutton(app.EntradaTab, 'push');
            app.CapturarButton.Text = 'Capturar';
            app.CargarmatButton = uibutton(app.EntradaTab, 'push');
            app.CargarmatButton.Text = 'Cargar .mat';
            app.FsEditFieldLabel = uilabel(app.EntradaTab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.EntradaTab, 'numeric');
            app.xEditFieldLabel = uilabel(app.EntradaTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.EntradaTab, 'numeric');
            app.TiemposEditFieldLabel = uilabel(app.EntradaTab);
            app.TiemposEditFieldLabel.Text = 'Tiempo (s)';
            app.TiemposEditField = uieditfield(app.EntradaTab, 'numeric');
            app.DiseoOfflineTab = uitab(app.TabGroup);
            app.DiseoOfflineTab.Title = 'Diseño Offline';
            app.UIAxes_2 = uiaxes(app.DiseoOfflineTab);
            title(app.UIAxes_2, 'Fase')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.DiseoOfflineTab);
            title(app.UIAxes2_2, 'Magnitud')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.EditField_4 = uieditfield(app.DiseoOfflineTab, 'text');
            app.TipodeFiltroButtonGroup = uibuttongroup(app.DiseoOfflineTab);
            app.TipodeFiltroButtonGroup.Title = 'Tipo de Filtro';
            app.IIRButton = uiradiobutton(app.TipodeFiltroButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.IIRButton.Value = true;
            app.FIRButton = uiradiobutton(app.TipodeFiltroButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FiltroDropDownLabel = uilabel(app.DiseoOfflineTab);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.DiseoOfflineTab);
            app.FiltroDropDown.Items = {'Butterworth', 'Chevy 1', 'Chevy 2', 'Bessel', 'Eliptico'};
            app.FiltroDropDown.Value = 'Butterworth';
            app.EditField_5 = uieditfield(app.DiseoOfflineTab, 'text');
            app.EfectosDropDownLabel = uilabel(app.DiseoOfflineTab);
            app.EfectosDropDownLabel.Text = 'Efectos';
            app.EfectosDropDown = uidropdown(app.DiseoOfflineTab);
            app.EfectosDropDown.Items = {'Eco', 'Distorsion', 'Pitch alto', 'Pitch bajo'};
            app.EfectosDropDown.Value = 'Eco';
            app.FcEditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.GananciaEditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.GananciaEditFieldLabel.Text = 'Ganancia';
            app.GananciaEditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.RetrasoEditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.RetrasoEditFieldLabel.Text = 'Retraso';
            app.RetrasoEditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.OrdenEditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.Fcorte1EditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.Fcorte1EditFieldLabel.Text = 'F corte 1';
            app.Fcorte1EditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.Fcorte2EditFieldLabel = uilabel(app.DiseoOfflineTab);
            app.Fcorte2EditFieldLabel.Text = 'F corte 2';
            app.Fcorte2EditField = uieditfield(app.DiseoOfflineTab, 'numeric');
            app.AplicarButton = uibutton(app.DiseoOfflineTab, 'push');
            app.AplicarButton.Text = 'Aplicar';
            app.AlternarejeHorizontalFaseButtonGroup = uibuttongroup(app.DiseoOfflineTab);
            app.AlternarejeHorizontalFaseButtonGroup.Title = 'Alternar eje Horizontal (Fase)';
            app.HzButton = uiradiobutton(app.AlternarejeHorizontalFaseButtonGroup);
            app.HzButton.Text = 'Hz';
            app.HzButton.Value = true;
            app.RadMuestraButton = uiradiobutton(app.AlternarejeHorizontalFaseButtonGroup);
            app.RadMuestraButton.Text = 'Rad/Muestra';
            app.AlternarejeHorizontalMagnitudButtonGroup = uibuttongroup(app.DiseoOfflineTab);
            app.AlternarejeHorizontalMagnitudButtonGroup.Title = 'Alternar eje Horizontal (Magnitud)';
            app.HzButton_2 = uiradiobutton(app.AlternarejeHorizontalMagnitudButtonGroup);
            app.HzButton_2.Text = 'Hz';
            app.HzButton_2.Value = true;
            app.RadMuestraButton_2 = uiradiobutton(app.AlternarejeHorizontalMagnitudButtonGroup);
            app.RadMuestraButton_2.Text = 'Rad/Muestra';
            app.AlternarejeVerticalMagnitudButtonGroup = uibuttongroup(app.DiseoOfflineTab);
            app.AlternarejeVerticalMagnitudButtonGroup.Title = 'Alternar eje Vertical (Magnitud)';
            app.dBButton = uiradiobutton(app.AlternarejeVerticalMagnitudButtonGroup);
            app.dBButton.Text = 'dB';
            app.dBButton.Value = true;
            app.LinealButton = uiradiobutton(app.AlternarejeVerticalMagnitudButtonGroup);
            app.LinealButton.Text = 'Lineal';
            app.DiseoOnlineTab = uitab(app.TabGroup);
            app.DiseoOnlineTab.Title = 'Diseño Online';
            app.SalidaTab = uitab(app.TabGroup);
            app.SalidaTab.Title = 'Salida';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end